classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TranslationLabel            matlab.ui.control.Label
        RotationLabel               matlab.ui.control.Label
        InstructionsEditField       matlab.ui.control.EditField
        InstructionsEditFieldLabel  matlab.ui.control.Label
        EditField_T3                matlab.ui.control.NumericEditField
        EditField_T2                matlab.ui.control.NumericEditField
        EditField_T1                matlab.ui.control.NumericEditField
        EditField_Theta3            matlab.ui.control.NumericEditField
        EditField_Theta2            matlab.ui.control.NumericEditField
        EditField_Theta1            matlab.ui.control.NumericEditField
        DisplayButton               matlab.ui.control.Button
        ForgroundSelectionButton    matlab.ui.control.Button
        RotatetheimagewiththeArrowkeysLabel  matlab.ui.control.Label
        EnableRotationSwitch        matlab.ui.control.Switch
        EnableRotationSwitchLabel   matlab.ui.control.Label
        ResetButton                 matlab.ui.control.Button
        Button_Start                matlab.ui.control.Button
        Button_Vanishingpoint       matlab.ui.control.Button
        Button_Backplane            matlab.ui.control.Button
        Button_Load                 matlab.ui.control.Button
        UIAxes_New_Image            matlab.ui.control.UIAxes
        UIAxes_3D_Image             matlab.ui.control.UIAxes
        UIAxes_Loaded_Image         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        
        VP
        
        VP_cord
        im
        rect
        position
        foreground
        vertices2d
        Pixels3D
        f
        px_h
        px_w
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_Load
        function Button_LoadPushed(app, event)
           
            %Define the selectable files
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};   
            %Select the Image and store the Filname and Path
            [file,path] = uigetfile(filterspec);  

            %Check if File was properly selected
            if isnumeric(file)||isnumeric(path)
            
            else
               
            imagefile = [path file];
            %Read the image to a Variable
            app.im = imread(imagefile);
            %Display the Image
            imagesc(app.UIAxes_Loaded_Image,app.im);
            axis(app.UIAxes_Loaded_Image, 'tight');
            app.Button_Backplane.Visible = 'on';
            app.InstructionsEditField.Value ='Press Homograpic and mark the Backplane. Confirm with doubleclick'
            end
        end

        % Button pushed function: Button_Backplane
        function Button_BackplanePushed(app, event)
            app.rect = imrect(app.UIAxes_Loaded_Image);
            app.position = wait(app.rect);
            app.Button_Vanishingpoint.Visible = 'on'
            app.InstructionsEditField.Value ='Press "Vanashingpoint and Select the Vanashing point in the Picture'
            % app.P1 = drawpoint(app.UIAxes_Loaded_Image);
            % app.P2 = drawpoint(app.UIAxes_Loaded_Image);
            % app.P3 = drawpoint(app.UIAxes_Loaded_Image);
            % app.P4 = drawpoint(app.UIAxes_Loaded_Image);
            % 
            % app.P1_cord = round(app.P1.Position);
            % app.P2_cord = round(app.P2.Position);
            % app.P3_cord = round(app.P3.Position);
            % app.P4_cord = round(app.P4.Position);
        end

        % Button pushed function: Button_Vanishingpoint
        function Button_VanishingpointPushed(app, event)
            app.VP = drawpoint(app.UIAxes_Loaded_Image);
            
            app.VP = round(app.VP.Position);
            app.ForgroundSelectionButton.Visible = 'on';
            app.InstructionsEditField.Value =' Select Foregroundobject'
        end

        % Button pushed function: Button_Start
        function Button_StartPushed(app, event)
           [ptCloud,app.Pixels3D,app.vertices2d,app.f,app.px_h,app.px_w] = func(app.im,app.position,app.VP,app.foreground);
            %player = pcplayer([0 10000],[0 10000],[0 10000]);
           %ptCloud = pointCloud([xx yy zz], Color=color);
           % while isOpen(player)
           %view(player,ptCloud);
           % end
           %hold(app.UIAxes_3D_Image,'on')
           %
           
           %pcviewer(ptCloud)
            pcshow(ptCloud,Parent = app.UIAxes_3D_Image,BackgroundColor= [0.94,0.94,0.94],ViewPlane="XY")
            %imagesc(app.UIAxes_New_Image,new_image);
            rotate3d(app.UIAxes_3D_Image,'off')


app.TranslationLabel.Visible = 'on';
app.RotationLabel.Visible = 'on';
app.EditField_T3.Visible= 'on';
app.EditField_T2.Visible= 'on';
app.EditField_T1.Visible= 'on';
app.EditField_Theta3.Visible= 'on';
app.EditField_Theta2.Visible = 'on';
app.EditField_Theta1.Visible= 'on';
app.DisplayButton.Visible= 'on';
app.RotatetheimagewiththeArrowkeysLabel.Visible= 'on';
app.EnableRotationSwitch.Visible= 'on';


            %plot3(app.UIAxes3,xx,yy,zz)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             delete(app.P1)
             delete(app.P2)   
        end

        % Callback function
        function changeviewButtonPushed(app, event)
            view(app.UIAxes_3D_Image,app.NumberofForegroundobjectsEditField.Value,app.EditField2.Value)
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            value = app.EnableRotationSwitch.Value;
            if strcmp(value,'On')
            switch key
                case 'rightarrow'
                    %app.UIAxes_3D_Image.CameraTarget = [0 0 0];
                   camorbit(app.UIAxes_3D_Image,-1,0,'camera',[1 0 0])
                    %rotate(app.UIAxes_3D_Image.,[1 0 0],25)
                    % [caz,cel] = view(app.UIAxes_3D_Image);
                    % caz = caz+1;
                    % view(app.UIAxes_3D_Image,caz,cel)
                case 'leftarrow'
                    camorbit(app.UIAxes_3D_Image,1,0,'camera',[1 0 0])
                    % [caz,cel] = view(app.UIAxes_3D_Image);
                    % caz = caz-1;
                    % view(app.UIAxes_3D_Image,caz,cel)
                case 'uparrow'
                    camorbit(app.UIAxes_3D_Image,0,1)
                    % [caz,cel] = view(app.UIAxes_3D_Image);
                    % cel = cel+1;
                    % view(app.UIAxes_3D_Image,caz,cel)
                case 'downarrow'
                    camorbit(app.UIAxes_3D_Image,0,-1)
                    % [caz,cel] = view(app.UIAxes_3D_Image);
                    % cel = cel-1;
                    % view(app.UIAxes_3D_Image,caz,cel)
            end
            end
        end

        % Button pushed function: ForgroundSelectionButton
        function ForgroundSelectionButtonPushed(app, event)
            %Call function forground objects each time
            

            app.foreground = drawpolygon(app.UIAxes_Loaded_Image);
            
            app.Button_Start.Visible = 'on';
            app.InstructionsEditField.Value ='Select Press the Start Button'
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
            %function call new image

            angle_rad=deg2rad([app.EditField_Theta1.Value,app.EditField_Theta2.Value,app.EditField_Theta3.Value]);   %assuming the input angle is sent in degrees, we need to convert it to radians

% Translation vector
T = [app.EditField_T1.Value;
     app.EditField_T2.Value;
     app.EditField_T3.Value];

% Rotational matrix in x axis
Rx = [1                  0                       0; 
       0                  cos(angle_rad(1))       -sin(angle_rad(1));
       0                  sin(angle_rad(1))       cos(angle_rad(1))] ;

% Rotational matrix in y axis
Ry = [cos(angle_rad(2))  0                       sin(angle_rad(2));
       0                  1                       0;
       -sin(angle_rad(2)) 0                       cos(angle_rad(2)) ];

% Rotational matrix in z axis
Rz = [cos(angle_rad(3))  -sin(angle_rad(3))      0;
       sin(angle_rad(3))  cos(angle_rad(3))       0;
       0                  0                       1];

R = Rx * Ry * Rz;

[newimage] = create_new_img(app.Pixels3D, app.vertices2d(13,1), app.vertices2d(13,2), app.f, R, T, app.px_h, app.px_w);
imagesc(app.UIAxes_New_Image,newimage);
axis(app.UIAxes_New_Image, 'tight');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIAxes_Loaded_Image = uiaxes(app.UIFigure);
            title(app.UIAxes_Loaded_Image, 'Image')
            zlabel(app.UIAxes_Loaded_Image, 'Z')
            app.UIAxes_Loaded_Image.XTick = [];
            app.UIAxes_Loaded_Image.YTick = [];
            app.UIAxes_3D_Image = uiaxes(app.UIFigure);
            title(app.UIAxes_3D_Image, '3D')
            app.UIAxes_3D_Image.XTick = [];
            app.UIAxes_3D_Image.YTick = [];
            app.UIAxes_3D_Image.ZTick = [];
            app.UIAxes_New_Image = uiaxes(app.UIFigure);
            title(app.UIAxes_New_Image, 'New Image')
            app.UIAxes_New_Image.XTick = [];
            app.UIAxes_New_Image.YTick = [];
            app.UIAxes_New_Image.ZTick = [];
            app.Button_Load = uibutton(app.UIFigure, 'push');
            app.Button_Load.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadPushed, true);
            app.Button_Load.Text = 'Load Image';
            app.Button_Backplane = uibutton(app.UIFigure, 'push');
            app.Button_Backplane.ButtonPushedFcn = createCallbackFcn(app, @Button_BackplanePushed, true);
            app.Button_Backplane.Visible = 'off';
            app.Button_Backplane.Text = 'Homographic';
            app.Button_Vanishingpoint = uibutton(app.UIFigure, 'push');
            app.Button_Vanishingpoint.ButtonPushedFcn = createCallbackFcn(app, @Button_VanishingpointPushed, true);
            app.Button_Vanishingpoint.Visible = 'off';
            app.Button_Vanishingpoint.Text = 'Vanashing Point';
            app.Button_Start = uibutton(app.UIFigure, 'push');
            app.Button_Start.ButtonPushedFcn = createCallbackFcn(app, @Button_StartPushed, true);
            app.Button_Start.Visible = 'off';
            app.Button_Start.Text = 'START';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Visible = 'off';
            app.ResetButton.Text = 'Reset';
            app.EnableRotationSwitchLabel = uilabel(app.UIFigure);
            app.EnableRotationSwitchLabel.Visible = 'off';
            app.EnableRotationSwitchLabel.Text = 'Enable Rotation';
            app.EnableRotationSwitch = uiswitch(app.UIFigure, 'slider');
            app.EnableRotationSwitch.Visible = 'off';
            app.RotatetheimagewiththeArrowkeysLabel = uilabel(app.UIFigure);
            app.RotatetheimagewiththeArrowkeysLabel.Visible = 'off';
            app.RotatetheimagewiththeArrowkeysLabel.Text = 'Rotate the image with the Arrowkeys ';
            app.ForgroundSelectionButton = uibutton(app.UIFigure, 'push');
            app.ForgroundSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ForgroundSelectionButtonPushed, true);
            app.ForgroundSelectionButton.Visible = 'off';
            app.ForgroundSelectionButton.Text = 'Forground Selection';
            app.DisplayButton = uibutton(app.UIFigure, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Visible = 'off';
            app.DisplayButton.Text = 'Display ';
            app.EditField_Theta1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_Theta1.Visible = 'off';
            app.EditField_Theta2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_Theta2.Visible = 'off';
            app.EditField_Theta3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_Theta3.Visible = 'off';
            app.EditField_T1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_T1.Visible = 'off';
            app.EditField_T1.Value = 10;
            app.EditField_T2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_T2.Visible = 'off';
            app.EditField_T2.Value = 10;
            app.EditField_T3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_T3.Visible = 'off';
            app.EditField_T3.Value = 400;
            app.InstructionsEditFieldLabel = uilabel(app.UIFigure);
            app.InstructionsEditFieldLabel.Text = 'Instructions:';
            app.InstructionsEditField = uieditfield(app.UIFigure, 'text');
            app.InstructionsEditField.Editable = 'off';
            app.InstructionsEditField.Value = 'Press "Load" to load an Image';
            app.RotationLabel = uilabel(app.UIFigure);
            app.RotationLabel.Visible = 'off';
            app.RotationLabel.Text = 'Rotation';
            app.TranslationLabel = uilabel(app.UIFigure);
            app.TranslationLabel.Visible = 'off';
            app.TranslationLabel.Text = 'Translation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end