classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        changeviewButton      matlab.ui.control.Button
        EditField2            matlab.ui.control.NumericEditField
        EditField2Label       matlab.ui.control.Label
        EditField             matlab.ui.control.NumericEditField
        EditFieldLabel        matlab.ui.control.Label
        ResetButton           matlab.ui.control.Button
        Panel                 matlab.ui.container.Panel
        UIAxes2               matlab.ui.control.UIAxes
        STARTButton           matlab.ui.control.Button
        VanashingPointButton  matlab.ui.control.Button
        HomographicButton     matlab.ui.control.Button
        Button                matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    properties (Access = private)
        P1
        P2
        P3
        P4
        VP
        P1_cord
        P2_cord
        P3_cord
        P4_cord
        VP_cord
        im
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [file,path] = uigetfile(filterspec);
            imagefile = [path file];
            app.im = imread(imagefile);
            imagesc(app.UIAxes,app.im);
        end

        % Button pushed function: HomographicButton
        function HomographicButtonPushed(app, event)
            
            app.P1 = drawpoint(app.UIAxes);
            app.P2 = drawpoint(app.UIAxes);
            app.P3 = drawpoint(app.UIAxes);
            app.P4 = drawpoint(app.UIAxes);

            app.P1_cord = round(app.P1.Position);
            app.P2_cord = round(app.P2.Position);
            app.P3_cord = round(app.P3.Position);
            app.P4_cord = round(app.P4.Position);
        end

        % Button pushed function: VanashingPointButton
        function VanashingPointButtonPushed(app, event)
            app.VP = drawpoint(app.UIAxes);
            
            app.VP = round(app.VP.Position);


        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
           [xx,yy,zz,color] = func(app.P1_cord,app.P2_cord,app.P3_cord,app.P4_cord,app.VP,app.im);
            %player = pcplayer([0 10000],[0 10000],[0 10000]);
           ptCloud = pointCloud([xx yy zz], Color=color);
           % while isOpen(player)
           %view(player,ptCloud);
           % end
           %hold(app.UIAxes2,'on')
           %
           
           %pcviewer(ptCloud)
            pcshow(ptCloud,Parent = app.UIAxes2,BackgroundColor= [0.94,0.94,0.94])
            rotate3d(app.UIAxes2,'on')
            %plot3(app.UIAxes3,xx,yy,zz)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             delete(app.P1)
             delete(app.P2)   
        end

        % Button pushed function: changeviewButton
        function changeviewButtonPushed(app, event)
            view(app.UIAxes2,app.EditField.Value,app.EditField2.Value)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key
            switch key
                case 'rightarrow'
                    app.EditField.Value=app.EditField.Value + 1;
                case 'leftarrow'
                    app.EditField.Value2=app.EditField2.Value + 1; 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.HomographicButton = uibutton(app.UIFigure, 'push');
            app.HomographicButton.ButtonPushedFcn = createCallbackFcn(app, @HomographicButtonPushed, true);
            app.HomographicButton.Text = 'Homographic';
            app.VanashingPointButton = uibutton(app.UIFigure, 'push');
            app.VanashingPointButton.ButtonPushedFcn = createCallbackFcn(app, @VanashingPointButtonPushed, true);
            app.VanashingPointButton.Text = 'Vanashing Point';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxes2 = uiaxes(app.Panel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.changeviewButton = uibutton(app.UIFigure, 'push');
            app.changeviewButton.ButtonPushedFcn = createCallbackFcn(app, @changeviewButtonPushed, true);
            app.changeviewButton.Text = 'change view';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end